classdef RecordAudioVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TimeElapsedEditField       matlab.ui.control.EditField
        TimeelapsedEditFieldLabel  matlab.ui.control.Label
        version_Label              matlab.ui.control.Label
        RecordAudioVideoLabel      matlab.ui.control.Label
        RecordButton               matlab.ui.control.StateButton
        StopButton                 matlab.ui.control.StateButton
        SettingsPanel              matlab.ui.container.Panel
        AnimalID_EditField         matlab.ui.control.EditField
        AnimalIDEditFieldLabel_2   matlab.ui.control.Label
        SaveDir_EditField          matlab.ui.control.EditField
        SavedirEditFieldLabel      matlab.ui.control.Label
        ChooseSaveDir_Button       matlab.ui.control.Button
        AudioinputsPanel           matlab.ui.container.Panel
        Cam6PreviewButton_2        matlab.ui.control.Button
        Mic6RecordCheckBox         matlab.ui.control.CheckBox
        Label_10                   matlab.ui.control.Label
        Cam5PreviewButton_2        matlab.ui.control.Button
        Mic5RecordCheckBox         matlab.ui.control.CheckBox
        Label_9                    matlab.ui.control.Label
        TTLchannelsLabel           matlab.ui.control.Label
        MicrophonesLabel           matlab.ui.control.Label
        Cam4PreviewButton_2        matlab.ui.control.Button
        Mic4RecordCheckBox         matlab.ui.control.CheckBox
        Cam3PreviewButton_2        matlab.ui.control.Button
        Mic3RecordCheckBox         matlab.ui.control.CheckBox
        Cam2PreviewButton_2        matlab.ui.control.Button
        Mic2RecordCheckBox         matlab.ui.control.CheckBox
        Cam1PreviewButton_2        matlab.ui.control.Button
        Mic1RecordCheckBox         matlab.ui.control.CheckBox
        Label_8                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        CameraPanel                matlab.ui.container.Panel
        ParallelPool_Lamp          matlab.ui.control.Lamp
        StartParallelPool_Button   matlab.ui.control.Button
        IPvideoEncoderEditField    matlab.ui.control.EditField
        IPLabel                    matlab.ui.control.Label
        Cam4Lamp                   matlab.ui.control.Lamp
        Cam3Lamp                   matlab.ui.control.Lamp
        Cam4PreviewButton          matlab.ui.control.Button
        Cam4RecordCheckBox         matlab.ui.control.CheckBox
        Cam3PreviewButton          matlab.ui.control.Button
        Cam3RecordCheckBox         matlab.ui.control.CheckBox
        Cam2Lamp                   matlab.ui.control.Lamp
        Cam1Lamp                   matlab.ui.control.Lamp
        Cam2PreviewButton          matlab.ui.control.Button
        Cam2RecordCheckBox         matlab.ui.control.CheckBox
        Cam1PreviewButton          matlab.ui.control.Button
        Cam1RecordCheckBox         matlab.ui.control.CheckBox
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        browseButtonCam            matlab.ui.control.Button
        CamSaveDir                 matlab.ui.control.EditField
        savedirEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        ba      % Params and other variables
        cam     % Camera object and settings
    end
    
    methods (Access = private)
        
        function close_video_and_save_cam_timestamps(app)
            % Close video files and save cam time stamps:
            
            if isfield(app.cam, 'recordingOn') && sum(app.cam.recordingOn) > 0
                fprintf('Closing video files and saving timestamps ...\n' );
                for cx = 1 : length(app.cam.recordingOn)
                    if app.cam.recordingOn(cx) > 0
                        cancel(app.cam.F{cx})
                        % Close cam timestamps binary file
                        % N.B. fclosing the .cam binary file from here does not
                        % work, probably because it was opened in a parallel worker. This
                        % means that the file cannot be deleted or modified until Matlab is
                        % closed.
                        % About why closing files:
                        % web('https://www.mathworks.com/matlabcentral/answers/443019-fclose-what-is-the-purpose');
            %             try
            %                 fid = fopen(app.cam.TimeStamps_fullpath_bin{cx}, 'w');
            %                 fclose(fid);
            %             catch
            %                 warning(['Some error closing the cam timestamps binary file: ' app.cam.TimeStamps_filename{cx}])
            %             end
                    end
                end
                try %#ok<TRYNC> % Keep the try statement because if F is deleted earlier, rmfield gives error.
                    app.cam = rmfield(app.cam, {'F'});
                end
                
                disp('Converting .cam binary files for camTimeStamps into .mat...') 
                for cx = 1 : length(app.cam.recordingOn)
                    if app.cam.recordingOn(cx) > 0
                        try
                            tic_camts_loading = tic;
                            fid = fopen(app.cam.TimeStamps_fullpath_bin{cx}, 'r');
                            % Read all data from the audio file in a vector
                            camTimeStamps = fread(fid,[Inf],'double');
                            % Close the file
                            fclose(fid);
                            % Save as an independent .mat file:
                            save(app.cam.TimeStamps_fullpath_mat{cx} , 'camTimeStamps');
                            % Store within vr as well:
                            app.cam.camTimeStamps{cx} = camTimeStamps;
                            disp(['  .cam binary file for camTimeStamps ' num2str(cx) ' saved as .mat! Time elapsed: ' num2str(toc(tic_camts_loading)) ' s']) 
                        catch
                            warning(['  .cam binary file for camTimeStamps ' num2str(cx) ' failed loading or saving as .mat!']) 
                        end
                    end
                end
            end
            %     figure;
            %     d=diff(camTimeStamps);
            %     histogram(d(2:end)); % first frame is often much slower
        end
        
        function update_parallel_pool_lamp(app)
            if ~isempty(gcp('nocreate'))
                app.ParallelPool_Lamp.Color = [0 1 0];
            else
                app.ParallelPool_Lamp.Color = [1 0 0];
            end
        end
        
        function prepare_camera_and_start_recording(app, trial_cnt)
                    %%============================
                    %% Camera settings and start recording
%                     tic_parfeval = tic;
                    %%
                    app.CamSaveDir.Value = app.ba.save_folder;
                    app.cam.saveDir      = app.CamSaveDir.Value;
                    app.cam.dateTag      = app.ba.date_str;
                    %% Create folder, get counter, close previews
                    cams_rec_val = [app.Cam1RecordCheckBox.Value,...
                                    app.Cam2RecordCheckBox.Value,...
                                    app.Cam3RecordCheckBox.Value,...
                                    app.Cam4RecordCheckBox.Value];
                    app.cam.recordingOn = cams_rec_val;
                    if sum(cams_rec_val) > 0 % if any cam recording is on
                        defaultDir = app.cam.saveDir; % this is defined in the startupFun
                        camDir     = app.CamSaveDir.Value;
                        try
                            if ~exist(camDir,'dir'), mkdir(camDir), end
                            app.cam.saveDir = camDir;
                        catch
                            warning(sprintf('\n some problem with newly entered path\n reverting back to previous path')) %#ok<SPWRN> 
                            app.CamSaveDir.Value = defaultDir;
                        end
                        % Get counter:
                        if isempty(trial_cnt)
                            video_cnt = 1;
                        else
                            video_cnt = trial_cnt;
                        end
                    end
                    for c = 1 : 4
                        try %#ok<TRYNC> 
                            closePreview(app.cam.camObj{c})
                        end
                    end
                    %%===========================
                    %% Camera settings and start recording

                    if sum(cams_rec_val) > 0 % if recording of any camera is on
                        poolObj = gcp(); % delete(gcp('nocreate'))
                        try %#ok<TRYNC> cancel possible existing running futures
                            for i = 1 : length(poolObj.FevalQueue.RunningFutures)
                                sfun = functions(poolObj.FevalQueue.RunningFutures(i).Function);
                                if contains(sfun.function, 'cam','IgnoreCase',true)
                                    cancel(poolObj.FevalQueue.RunningFutures(:))
                                end
                            end
                        end
                    
                        for cx = 1 : length(cams_rec_val)
                            if cams_rec_val(cx) > 0
                                cNr = cx;
                                video_cnt_str = num2str(video_cnt,'%03d');
                                app.cam.video_filename{cx}          = [app.ba.animalID '_' app.cam.dateTag '_Cam' num2str(cNr) '_' video_cnt_str '.avi'];
                                app.cam.TimeStamps_filename{cx}     = [app.ba.animalID '_' app.cam.dateTag '_Cam' num2str(cNr) '_' video_cnt_str '_CamTimeStamps'];
                                app.cam.TimeStamps_fullpath_bin{cx} = fullfile(camDir, [app.cam.TimeStamps_filename{cx} '.cam']);
                                app.cam.TimeStamps_fullpath_mat{cx} = fullfile(camDir, [app.cam.TimeStamps_filename{cx} '.mat']);
                    %             vr.cam.TimeStamps_fid{cx}          = fopen(vr.cam.TimeStamps_fullpath_bin{cx},'w');
                                app.cam.F{cx} = parfeval(@Record2Cam_fwrite, 0, app.cam.camIP{cx}, app.cam.video_filename{cx}, ...
                                    [], [], app.cam.saveDir, app.cam.username, app.cam.password, app.cam.TimeStamps_fullpath_bin{cx});
                            end
                        end

                        update_parallel_pool_lamp(app);
                        tic_camera_check = tic;
                    end
                    
                    %% Check if camera is being saved
                    % It once happened that cameras were set to Record ON but no files and
                    % timestamps were acquired. So let's make sure that files are being saved
                    % before we start
                    if any(cams_rec_val)
                        disp('Checking if camera video files are being saved...')
                        for cx = find(cams_rec_val)
                            camfile_fullpath = fullfile(app.cam.saveDir, app.cam.video_filename{cx});
                            while ~isfile(camfile_fullpath) && toc(tic_camera_check) < 10 % wait up to 10 seconds  % && toc(tic_camera_check) < 2 % give enough time to create the file (if you get to this check too quickly, you might get error even though  all is fine)
                                pause(0.1)
                            end
                            if ~isfile(camfile_fullpath)
%                                 message = sprintf('Video from camera %d is not being saved even if Record is ON! \nSomething weird happened, I am aborting trial %d \nTry restarting...',cx,tr.trial_cnt);
                                message = sprintf('Video from camera %d is not being saved even if Record is ON! \nSomething weird happened in trial %d \nTrial is starting anyway...',cx,trial_cnt);
                                disp(message)
%                                 uialert(app.UIFigure, message,'Camera error!', 'Modal',true);
                                uialert(app.UIFigure, message,'Camera error!', 'Modal',false);
%                                 uiwait(app.UIFigure,5)
%                                 error(message) %#ok<SPERR>
                            end
                        end
                        disp([char(datetime('now','Format','HH:mm:ss')) ' - ...okay.'])
                    end
%                     toc(tic_parfeval)
%                     fprintf('\n\n\n')
        end
        
        function prepare_audiorec_and_start_recording(app, trial_cnt)
            if any(app.ba.record_audio)
                % Get counter:
                if isempty(trial_cnt)
                    mic_cnt = 1;
                else
                    mic_cnt = trial_cnt;
                end
                mic_cnt_str = num2str(mic_cnt,'%03d');
                app.ba.audio_rec.filename     = [app.ba.animalID '_' app.ba.date_str '_audiorec_' mic_cnt_str];
                app.ba.audio_rec.fullpath_bin = fullfile(app.ba.save_folder, [app.ba.audio_rec.filename '.mic']);
                app.ba.audio_rec.fullpath_wav = fullfile(app.ba.save_folder, [app.ba.audio_rec.filename '.wav']);
                app.ba.audio_rec.fullpath_mat = fullfile(app.ba.save_folder, [app.ba.audio_rec.filename '_MicTimeStamps.mat']);
                app.ba.audio_rec.fid          = fopen(app.ba.audio_rec.fullpath_bin,'w');
                app.ba.audio_rec.tLastSave    = GetSecs(); % initialize timestamp, no data saved at this timestamp.
                % Initialize timestamps and nr.samples. These will be saved together
                % with vr variable and also in a separate file:
                app.ba.audio_rec.MicTimeStamps        = []; % timestamp of the first sample of each iteration, i.e. audiodata(1) (in GetSecs time).
                app.ba.audio_rec.MicNrSamples         = []; % nr. of samples of each iteration.
                app.ba.audio_rec.BufferOverflow       = []; % will store timestamp of iterations with buffer overflow
                app.ba.audio_rec.nAudioChannels_input_toSave = sum(app.ba.record_audio);

                % Start audio recording!
                PsychPortAudio('Start', app.ba.audio_rec.devHandleRec);
                
                fprintf([char(datetime('now','Format','HH:mm:ss')) ' - Audio recording started! \n']);
            end
        end
        
        function get_audio_data_and_save(app, checkInterSavingInterval)
            % With checkInterSavingInterval = 0, InterSavingInterval is
            % ignored and audio data is retrieved and saved.
            if any(app.ba.record_audio)
                currTime = GetSecs();
                if (checkInterSavingInterval && (currTime - app.ba.audio_rec.tLastSave) > app.ba.audio_rec.InterSavingInterval ) ...
                    || ~checkInterSavingInterval
                    tic_audio_rec_saving = tic;
                    singleType = 1; %single() type matrices only consume half as much memory as double() type matrices, without loss of audio precision for up to 24-Bit ADC hardware.
                    % Retrieve pending audio data from the drivers internal ringbuffer:
                    % ‘tCaptureStart’ this is an estimate of the system time (in GetSecs seconds) when the very
                    % first sample of this recording was captured by the sound input of your hardware.
                    % This is only to be trusted down to the millisecond level after former careful
                    % calibration of your setup!
                    % web('http://psychtoolbox.org/docs/PsychPortAudio-GetAudioData')
                    [audiodata, offset, overflow, tCaptureStart] = PsychPortAudio('GetAudioData', app.ba.audio_rec.devHandleRec, [],[],[],singleType);
                    % Save only the nr. input channels specified in the GUI
                    audiodata = audiodata(app.ba.record_audio, :);
                    % Append audio data to binary .mic file:
                    fwrite(app.ba.audio_rec.fid, audiodata, 'single');
                    % Calculate timestamp of the first sample of this iteration, i.e. audiodata(1) (in GetSecs time):
                    MicTimeStamp = tCaptureStart + (offset  / app.ba.audio_rec.SampleRate);
                    NrSamples = size(audiodata, 2);
                    % Store mic timestamp of this iteration:
                    app.ba.audio_rec.MicTimeStamps  = [app.ba.audio_rec.MicTimeStamps; MicTimeStamp];
                    % Store nr. of samples of this iteration:
                    app.ba.audio_rec.MicNrSamples   = [app.ba.audio_rec.MicNrSamples; NrSamples];
                    if overflow > 0
                        % ‘overflow’ if this flag is zero then everything went fine. If it is one then you
                        % didn’t manage to call this function frequent enough, the capacity of the
                        % internal recording buffer was exceeded and therefore you lost captured sound
                        % data, i.e., there is a gap in your recording. When initially allocating the
                        % internal buffer, make sure to allocate it big enough so it is able to easily
                        % store all recorded data inbetween your calls to ‘GetAudioData’
                        % web('http://psychtoolbox.org/docs/PsychPortAudio-GetAudioData');
                        warning([char(datetime('now','Format','HH:mm:ss')) ' - Buffer overflow for audio recording! There is a gap in your recording'])
                        app.ba.audio_rec.BufferOverflow = [app.ba.audio_rec.BufferOverflow; MicTimeStamp]; 
                    end
                    app.ba.audio_rec.tLastSave = currTime;
                    toc_audio_rec_saving       = toc(tic_audio_rec_saving); %#ok<NASGU>
            %         disp([datestr(now,'yyyy-mm-dd HH:MM:SS') ' - recorded audio data saved (it took ' num2str(toc_audio_rec_saving*1000,'%3.1f') ' msec)'])
                end
            end
        end
        
        function stop_audio_recording_and_save(app, convertToWav)
            if nargin < 2
                convertToWav = 0;
            end
            
            if any(app.ba.record_audio)
                % Stop audio capture:
                PsychPortAudio('Stop', app.ba.audio_rec.devHandleRec);
                % Close recorded audio .mic file:
                fclose(app.ba.audio_rec.fid);
                MicTimeStamps  = app.ba.audio_rec.MicTimeStamps;
                MicNrSamples   = app.ba.audio_rec.MicNrSamples;
                BufferOverflow = app.ba.audio_rec.BufferOverflow;
                save(app.ba.audio_rec.fullpath_mat, 'MicTimeStamps','MicNrSamples','BufferOverflow');
                disp(['Audio recording .mic file saved!']) 
                % Further below, .mic file is converted into .wav
            end
            
            % Convert .mic file into .wav.
            if any(app.ba.record_audio) && convertToWav
                disp('Converting recorded audio .mic binary file into .wav...') 
                tic_wav_conversion = tic;
                fid = fopen(app.ba.audio_rec.fullpath_bin, 'r');
                % Read all data from the audio file in a vector
                recaudiodata = fread(fid,[app.ba.audio_rec.nAudioChannels_input_toSave Inf],'single=>single')';
                % Close the file
                fclose(fid);
                % Save .wav file:
                audiowrite(app.ba.audio_rec.fullpath_wav, recaudiodata, app.ba.audio_rec.SampleRate, 'BitsPerSample',24);
                disp(['  .mic file saved as .wav! Time elapsed: ' num2str(toc(tic_wav_conversion)) ' s']) 
            end
        end
        
        function [sounds, audio_rec] = OpenAudioDevice( ~, record_audio )

            if nargin < 1 || isempty(record_audio)
                record_audio = false;
            end
            
            % Running on PTB-3? Abort otherwise.
            AssertOpenGL;
            % Perform basic initialization of the sound driver:
            reallyneedlowlatency = 1;
            InitializePsychSound(reallyneedlowlatency);
            
            
            % Get PsychPortAudio stuff
            dev = PsychPortAudio('getDevices');
            
            device_APInames = {dev.HostAudioAPIName}';
            device_names    = {dev.DeviceName}';
            % Devices to use, in the order specified in device_names_toUse:
            device_names_toUse = {'Fireface','Yamaha','ASIO4ALL'};
            for d = 1 : length(device_names_toUse)
                devind          = find(strcmp(device_APInames,'ASIO') & contains(device_names,device_names_toUse{d}));
                devind          = devind([dev(devind).NrOutputChannels] > 0);
                if length(devind) == 1
                    break
                end
            end
            if length(devind) == 1
                if contains(dev(devind).DeviceName, 'Fireface')
                    desiredFS   = 192000; %48000
            %         selectedChannels_Fireface = [1:4; 1:4]; % Port nr. as in "Balanced Line Outputs" on the audio card
            %         selectedChannels = selectedChannels_Fireface-1; % PsychPortAudio wants # one less (it starts counting from zero)
                    % N.B. Channel selection on PsychPortAudio('Open') is not supported
                    % with this audio backend (?). So this means that you cannot
                    % specify the physical device channels for output. You can
                    % indirectly do this, because the channels are output in sequence
                    % from physical channel 1 onward.
                    selectedChannels = [];
                    sounds.soundCard_name = 'Fireface UCX';
                    disp('     Fireface UCX with ASIO found')
                elseif contains(dev(devind).DeviceName, 'Yamaha')
                    % Yamaha Steinberg with ASIO is able to output 4 (or perhaps 6)
                    % channels in parallel. 
                    % N.B. Channel selection on PsychPortAudio('Open') is not supported
                    % on this card. So this means that you cannot specify the physical
                    % device channels for output. You can indirectly do this, because
                    % the channels are output in sequence from physical channel 1
                    % onward.
                    selectedChannels = []; % 
                    desiredFS   = 192000; %48000
                    sounds.soundCard_name = 'Yamaha Steinberg UR44';
                    disp('     Yamaha Steinberg UR44 with ASIO found')
                elseif contains(dev(devind).DeviceName, 'ASIO4ALL')
                    desiredFS   = 44100;
                    selectedChannels = []; % 
                    sounds.soundCard_name = 'Other sound card';
                    disp('     Other sound card (i.e. no Fireface or Yamaha Steinberg) with ASIO4ALL')
                end
                deviceID = dev(devind).DeviceIndex;
            else %if isempty(devind) || length(devind) > 1
                deviceID = 0;
                devind   = 1;
                disp(' /!\ ')
                disp(' /!\/!\ Sound card not found, improvising with deviceID = 0')
                disp([' /!\/!\ HostAudioAPIName: ' dev(devind).HostAudioAPIName ])
                disp([' /!\/!\ DeviceName: ' dev(devind).DeviceName ])
                disp(' /!\/!\/!\ ')
            end
            
            % Get PsychPortAudio ready
            try %#ok<TRYNC>
                % Delete all dynamic audio buffers:
                PsychPortAudio('DeleteBuffer');
                % Close audio device, shutdown driver:
                PsychPortAudio('Close');
            end
            
            % desiredFS = 192000; %48000 %192000
            reqlatencyclass = 2;
            if record_audio % sound playback AND recording
                modeOper       = 2+8; % 2 for recording, 3 for simultaneous capture and playback of sound; +8 to open as master device.
                % Open device with max possible nr. input channels and get 
                % audio data from all of them:
                % (Then, save only the nr. input channels specified in the
                % GUI)
                nAudioChannels_input = 6; %4; %[nr.input] 
                nAudioChannels       = nAudioChannels_input; %[nr.input] %[nr.output nr.input] in case of modeOper 3
            else % only sound playback
                modeOper       = 1+8; % 1 for playback of sound; +8 to open as master device.
                nAudioChannels = 6; %[nr.output] 
            end
            
            
            % web('http://psychtoolbox.org/docs/PsychPortAudio-Open');
            % (1) [] = default sound device
            % (2) 1 = mode of operation: sound playback only
            % (3) 1 = reqlatencyclass: default level of latency
            % (4) Requested frequency in samples per second
            % (5) output channels (supported only for Fireface, not for Yamaha)
            sounds.devHandleMaster = PsychPortAudio('Open', deviceID, modeOper, reqlatencyclass, desiredFS, nAudioChannels, [],[],selectedChannels);
            
            % Start master immediately, wait for it to be started. We won't stop the
            % master
            PsychPortAudio('Start', sounds.devHandleMaster, 0, 0, 1);
            
            
            % Retrieve status once to get access to SampleRate and other params:
            devStatus = PsychPortAudio('GetStatus', sounds.devHandleMaster);
            
            % Add a digital tag on the other channel
            pulseLoDuration_sec  = 0.0005;
            pulseHiDuration_sec  = 0.0005;
            deadTimeDuration_sec = 0.0001;
            % Store sound parameters:
            sounds.devParam.device           = dev(devind);
            sounds.devParam.modeOper         = modeOper;
            sounds.devParam.nAudioChannels   = nAudioChannels;
            sounds.devParam.desiredFS        = desiredFS;
            sounds.devParam.reqlatencyclass  = reqlatencyclass;
            sounds.devParam.selectedChannels = selectedChannels;
            sounds.SampleRate             = devStatus.SampleRate;
            sounds.PredictedOutputLatency = devStatus.PredictedLatency;
            sounds.BufferSize             = devStatus.BufferSize;
            % sounds.soundsToUse            = soundsToUse;
            sounds.pulseLoDuration_sec    = pulseLoDuration_sec;
            sounds.pulseHiDuration_sec    = pulseHiDuration_sec;
            sounds.deadTimeDuration_sec   = deadTimeDuration_sec;
            sounds.time_ptb               = [];


            %% Prepare for audio input recording and start recording
            
            if record_audio
                
                % Open slave device dedicated to recording (modeOper = 2)
                % web('http://psychtoolbox.org/docs/PsychPortAudio-OpenSlave')
                audio_rec.devHandleRec  = PsychPortAudio('OpenSlave', sounds.devHandleMaster, 2, nAudioChannels_input);
            
                % Preallocate an internal audio recording buffer with a capacity of 10 seconds:
                % You must call this function once before start of capture operations to allocate
                % an internal buffer that stores captured audio data inbetween your periodic
                % calls. Provide ‘amountToAllocateSecs’ as requested buffersize in seconds. After
                % start of capture you must call this function periodically at least every
                % ‘amountToAllocateSecs’ seconds to drain the internal buffer into your matrix
                % ‘audiodata’. If you fail to call the function frequently enough, sound data will
                % get lost!
                % web('http://psychtoolbox.org/docs/PsychPortAudio-GetAudioData')
                PsychPortAudio('GetAudioData', audio_rec.devHandleRec, 100);
            
                audio_rec.InterSavingInterval  = 0.1; % sec
                audio_rec.SampleRate           = sounds.SampleRate;
                audio_rec.nAudioChannels_input = nAudioChannels_input;
            else
                audio_rec  = [];
            end
                
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            fprintf('\n\n')
            fprintf('Starting RecordAudioVideo ... \n\n')
            % Add folder (without subfolders) to Matlab path:
            addpath(fileparts( which('RecordAudioVideo.mlapp')));
            addpath(fullfile(fileparts( which('RecordAudioVideo.mlapp')),'SupportingFunctions'));
            
            %% Audio device
            
            % Open Audio Device:
            % force record_audio==1 because this just opens the audio 
            % device with possibilty for recording audio; 
            % the important audio recording settings are in prepare_sound_output_and_record
            record_audio = 1; % force record_audio==1 because this just opens the audio device with possibilty for recording audio; the important audio recording settings are in prepare_sound_output_and_record
            % app.ba.record_audio = vr.app.RecordAudio.Value;
            [app.ba.sounds, app.ba.audio_rec] = OpenAudioDevice( app, record_audio );
            fprintf('\n\n')
            
            
            app.ba.animalID    = app.AnimalID_EditField.Value;
            app.ba.date_str    = char(datetime('now','Format','yyyy-MM-dd'));
%             app.ba.save_folder = 'C:\Users\dailyuser\Documents\Matlab\RecordAudioVideo';
            app.ba.save_folder_root = app.SaveDir_EditField.Value;
            app.ba.save_folder      = fullfile(app.ba.save_folder_root, app.ba.animalID, app.ba.date_str);

            fprintf('\n\n')
            
            %%%%%%%%%%%%%%%%
            %% Start up cameras
            app.CamSaveDir.Value = app.ba.save_folder;
            app.cam.IPvideoEncoder = app.IPvideoEncoderEditField.Value;
%             app.cam.IPvideoEncoder = '172.22.87.108'; % Neuropixels rig
            app.cam.username = 'dailyuser';
            app.cam.password = 'zebraFINCH^@';
            app.cam.saveDir  = app.CamSaveDir.Value;
            app.cam.dateTag  = app.ba.date_str;

            for c = 1 : 4
                cNr = num2str(c);
                app.cam.Nr{c}    = cNr;
                app.cam.camIP{c} = ['http://' app.cam.IPvideoEncoder '/mjpg/', cNr, '/video.mjpg'];
                CamLamp = ['Cam' cNr 'Lamp'];
                try
                    app.cam.camObj{c} = ipcam(app.cam.camIP{c}, app.cam.username, app.cam.password);
                    app.(CamLamp).Color = [0 1 0];
                catch
                    addons = matlab.addons.installedAddons;
                    if c==1 && ~contains("MATLAB Support Package for IP Cameras",addons.Name)
                            uialert(app.ViRMEndialogUIFigure,{['You need to install MATLAB Support Package for IP Cameras'];...
                                                              ['| ! | Cameras will not work | ! |']},...
                                    'IPcam toolbox missing',...
                                    'Icon','warning');
                    end
                    app.(CamLamp).Color = [1 0 0];
                    disp(['Cam' cNr ' not found'])
                end
            end
            update_parallel_pool_lamp(app);
            %%%%%%%%%%%%%%%%
            
            
            fprintf('\n*********************\n')
            fprintf('   RecordAudioVideo \n')
            fprintf('       ready !    \n')
            fprintf('*********************\n')
            
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            value = app.RecordButton.Value;

            if value

                %% Disable GUI panels
                app.SettingsPanel.Enable    = "off";
                app.CameraPanel.Enable      = "off";
                app.AudioinputsPanel.Enable = "off";
                % Update app:
                drawnow;

                %% Get data folder
                animalID           = app.AnimalID_EditField.Value;
                app.ba.animalID    = animalID;
                app.ba.date_str    = char(datetime('now','Format','yyyy-MM-dd'));
                app.ba.save_folder_root = app.SaveDir_EditField.Value;
                save_dir_curr      = fullfile(app.ba.save_folder_root, animalID, app.ba.date_str);
                app.ba.save_folder = save_dir_curr;

                %% Get incremental counter for filenames
                % Search in the data folder for previous files and get
                % counter from there, or start from 1.
                if isfolder(save_dir_curr)
                    ls{1,1} = dir([save_dir_curr filesep '*.mp4']);
                    ls{2,1} = dir([save_dir_curr filesep '*.mic']);
                    ls{3,1} = dir([save_dir_curr filesep '*.avi']);
                    ls{4,1} = dir([save_dir_curr filesep '*.wav?']);
                    ls_filenames = [ {ls{1}(:).name}'; {ls{2}(:).name}'; {ls{3}(:).name}'; {ls{4}(:).name}'];
                    cnt_last = max( cellfun(@(x)str2double(x(end-6:end-4)), ls_filenames) );
                    trial_cnt = cnt_last + 1;
                    if isempty(trial_cnt) % no .mp4 or .mic files in the folder
                        trial_cnt = 1;
                    end
                else
                    mkdir(save_dir_curr);
                    trial_cnt = 1;
                end
                app.ba.trial_cnt = trial_cnt;
                fprintf([char(datetime('now','Format','HH:mm:ss')) ' - Starting trial %d! \n'], trial_cnt);


                %% Start video recording
                prepare_camera_and_start_recording(app, trial_cnt);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                %% Start audio input recording
                for m = 1 : app.ba.audio_rec.nAudioChannels_input
                    app.ba.record_audio(m) = app.(['Mic' num2str(m) 'RecordCheckBox']).Value;
                end
                prepare_audiorec_and_start_recording(app, trial_cnt);

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                fprintf(['\n' char(datetime('now','Format','uuuu-MM-dd HH:mm:ss')) '\n Recording started! \n\n'])
                
                TrialIsRunning = 1;
                tic_trial = tic;

                while TrialIsRunning

                    %% Get audio data and save it:
                    get_audio_data_and_save(app, 1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                    % Update Time elapsed for this trial:
                    trial_te = toc(tic_trial);
                    te_h = floor(trial_te / 3600);
                    te_m = floor(trial_te / 60);
                    te_s = round(rem(trial_te, 60));
                    app.TimeElapsedEditField.Value = sprintf('%02dh :%02dm : %02ds',te_h,te_m,te_s);
                    
                    % Update app (to be able to check on buttons!)
                    drawnow;

                    if app.StopButton.Value > 0 || app.RecordButton.Value < 1
                        TrialIsRunning = 0;

                        fprintf([char(datetime('now','Format','HH:mm:ss')) ' - Stopping recording... \n'])
                        try
                            %% Close video and audio capture
                            close_video_and_save_cam_timestamps(app);
                            % Audio
                            get_audio_data_and_save(app, 0);
                            stop_audio_recording_and_save(app,1)
                        catch 
                            fprintf('\n <!> Warning <!> \n Closing video or audio capture failed! \n\n')
                        end

                        %% Re-enable GUI panels and buttons
                        app.SettingsPanel.Enable    = "on";
                        app.CameraPanel.Enable      = "on";
                        app.AudioinputsPanel.Enable = "on";
                        app.StopButton.Value   = 0;
                        app.RecordButton.Value = 0;

                        fprintf([' All done. Ready to start a new recording! \n\n'])
                        
                    end
                end


            else % Recording has been stopped


            end
        end

        % Button pushed function: ChooseSaveDir_Button
        function ChooseSaveDir_ButtonPushed(app, event)
            try %#ok<TRYNC> 
                selpath = uigetdir(app.ba.save_folder_root,'Select root folder to save data...');
                app.SaveDir_EditField.Value = selpath;
                app.ba.save_folder_root = selpath;
            end
        end

        % Button pushed function: Cam1PreviewButton
        function Cam1PreviewButtonPushed(app, event)
            try
                preview(app.cam.camObj{1})
            catch
                disp('Cam1 not connected')
            end
        end

        % Button pushed function: Cam2PreviewButton
        function Cam2PreviewButtonPushed(app, event)
            try
                preview(app.cam.camObj{2})
            catch
                disp('Cam2 not connected')
            end
        end

        % Button pushed function: Cam3PreviewButton
        function Cam3PreviewButtonPushed(app, event)
            try
                preview(app.cam.camObj{3})
            catch
                disp('Cam3 not connected')
            end
        end

        % Button pushed function: Cam4PreviewButton
        function Cam4PreviewButtonPushed(app, event)
            try
                preview(app.cam.camObj{4})
            catch
                disp('Cam4 not connected')
            end
        end

        % Value changed function: IPvideoEncoderEditField
        function IPvideoEncoderEditFieldValueChanged(app, event)
            app.cam.IPvideoEncoder = app.IPvideoEncoderEditField.Value;
            
            while app.RecordButton.Value > 0
                pause(0.1)
            end
            for c = 1 : 4
                cNr = num2str(c);
                app.cam.Nr{c}    = cNr;
                app.cam.camIP{c} = ['http://' app.cam.IPvideoEncoder '/mjpg/', cNr, '/video.mjpg'];
                CamLamp = ['Cam' cNr 'Lamp'];
                try
                    app.cam.camObj{c} = ipcam(app.cam.camIP{c}, app.cam.username, app.cam.password);
                    app.(CamLamp).Color = [0 1 0];
                catch
                    addons = matlab.addons.installedAddons;
                    if c==1 && ~contains("MATLAB Support Package for IP Cameras",addons.Name)
                            uialert(app.ViRMEndialogUIFigure,{['You need to install MATLAB Support Package for IP Cameras'];...
                                                              ['| ! | Cameras will not work | ! |']},...
                                    'IPcam toolbox missing',...
                                    'Icon','warning'); %#ok<*NBRAK2> 
                    end
                    app.(CamLamp).Color = [1 0 0];
                    disp(['Cam' cNr ' not found'])
                end
            end
            update_parallel_pool_lamp(app);
        end

        % Button pushed function: StartParallelPool_Button
        function StartParallelPool_ButtonPushed(app, event)
            poolObj = gcp(); %#ok<NASGU> % delete(gcp('nocreate'))
            while ~exist('poolObj','var')
                pause(0.1)
            end
            update_parallel_pool_lamp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CameraPanel = uipanel(app.UIFigure);
            app.CameraPanel.Title = 'Videoencoder Cameras';
            app.savedirEditFieldLabel = uilabel(app.CameraPanel);
            app.savedirEditFieldLabel.Enable = 'off';
            app.savedirEditFieldLabel.Text = 'save dir';
            app.CamSaveDir = uieditfield(app.CameraPanel, 'text');
            app.CamSaveDir.Editable = 'off';
            app.CamSaveDir.Enable = 'off';
            app.CamSaveDir.Value = 'D:\data_temp\ipcam_test\';
            app.browseButtonCam = uibutton(app.CameraPanel, 'push');
            app.browseButtonCam.Enable = 'off';
            app.browseButtonCam.Text = 'browse';
            app.Label = uilabel(app.CameraPanel);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.CameraPanel);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.CameraPanel);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.CameraPanel);
            app.Label_4.Text = '4';
            app.Cam1RecordCheckBox = uicheckbox(app.CameraPanel);
            app.Cam1RecordCheckBox.Text = 'Record';
            app.Cam1RecordCheckBox.Value = true;
            app.Cam1PreviewButton = uibutton(app.CameraPanel, 'push');
            app.Cam1PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @Cam1PreviewButtonPushed, true);
            app.Cam1PreviewButton.Text = 'View';
            app.Cam2RecordCheckBox = uicheckbox(app.CameraPanel);
            app.Cam2RecordCheckBox.Text = 'Record';
            app.Cam2PreviewButton = uibutton(app.CameraPanel, 'push');
            app.Cam2PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @Cam2PreviewButtonPushed, true);
            app.Cam2PreviewButton.Text = 'View';
            app.Cam1Lamp = uilamp(app.CameraPanel);
            app.Cam2Lamp = uilamp(app.CameraPanel);
            app.Cam3RecordCheckBox = uicheckbox(app.CameraPanel);
            app.Cam3RecordCheckBox.Text = 'Record';
            app.Cam3PreviewButton = uibutton(app.CameraPanel, 'push');
            app.Cam3PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @Cam3PreviewButtonPushed, true);
            app.Cam3PreviewButton.Text = 'View';
            app.Cam4RecordCheckBox = uicheckbox(app.CameraPanel);
            app.Cam4RecordCheckBox.Text = 'Record';
            app.Cam4PreviewButton = uibutton(app.CameraPanel, 'push');
            app.Cam4PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @Cam4PreviewButtonPushed, true);
            app.Cam4PreviewButton.Text = 'View';
            app.Cam3Lamp = uilamp(app.CameraPanel);
            app.Cam4Lamp = uilamp(app.CameraPanel);
            app.IPLabel = uilabel(app.CameraPanel);
            app.IPLabel.Text = 'IP';
            app.IPvideoEncoderEditField = uieditfield(app.CameraPanel, 'text');
            app.IPvideoEncoderEditField.ValueChangedFcn = createCallbackFcn(app, @IPvideoEncoderEditFieldValueChanged, true);
            app.IPvideoEncoderEditField.Value = '172.22.112.165';
            app.StartParallelPool_Button = uibutton(app.CameraPanel, 'push');
            app.StartParallelPool_Button.ButtonPushedFcn = createCallbackFcn(app, @StartParallelPool_ButtonPushed, true);
            app.StartParallelPool_Button.Tooltip = {'Manually start parallel pool, it takes ~10sec (if not started yet, it will start automatically when you start recording)'};
            app.StartParallelPool_Button.Text = 'Start parallel';
            app.ParallelPool_Lamp = uilamp(app.CameraPanel);
            app.AudioinputsPanel = uipanel(app.UIFigure);
            app.AudioinputsPanel.Title = 'Audio inputs';
            app.Label_5 = uilabel(app.AudioinputsPanel);
            app.Label_5.Text = '1';
            app.Label_6 = uilabel(app.AudioinputsPanel);
            app.Label_6.Text = '2';
            app.Label_7 = uilabel(app.AudioinputsPanel);
            app.Label_7.Text = '3';
            app.Label_8 = uilabel(app.AudioinputsPanel);
            app.Label_8.Text = '4';
            app.Mic1RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic1RecordCheckBox.Text = 'Record';
            app.Mic1RecordCheckBox.Value = true;
            app.Cam1PreviewButton_2 = uibutton(app.AudioinputsPanel, 'push');
            app.Cam1PreviewButton_2.Enable = 'off';
            app.Cam1PreviewButton_2.Tooltip = {'View buttons not implemented. Use OceanAudio to live view audio signal from the microphones.'};
            app.Cam1PreviewButton_2.Text = 'View';
            app.Mic2RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic2RecordCheckBox.Text = 'Record';
            app.Mic2RecordCheckBox.Value = true;
            app.Cam2PreviewButton_2 = uibutton(app.AudioinputsPanel, 'push');
            app.Cam2PreviewButton_2.Enable = 'off';
            app.Cam2PreviewButton_2.Tooltip = {'View buttons not implemented. Use OceanAudio to live view audio signal from the microphones.'};
            app.Cam2PreviewButton_2.Text = 'View';
            app.Mic3RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic3RecordCheckBox.Text = 'Record';
            app.Mic3RecordCheckBox.Value = true;
            app.Cam3PreviewButton_2 = uibutton(app.AudioinputsPanel, 'push');
            app.Cam3PreviewButton_2.Enable = 'off';
            app.Cam3PreviewButton_2.Tooltip = {'View buttons not implemented. Use OceanAudio to live view audio signal from the microphones.'};
            app.Cam3PreviewButton_2.Text = 'View';
            app.Mic4RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic4RecordCheckBox.Text = 'Record';
            app.Mic4RecordCheckBox.Value = true;
            app.Cam4PreviewButton_2 = uibutton(app.AudioinputsPanel, 'push');
            app.Cam4PreviewButton_2.Enable = 'off';
            app.Cam4PreviewButton_2.Tooltip = {'View buttons not implemented. Use OceanAudio to live view audio signal from the microphones.'};
            app.Cam4PreviewButton_2.Text = 'View';
            app.MicrophonesLabel = uilabel(app.AudioinputsPanel);
            app.MicrophonesLabel.Text = 'Microphones';
            app.TTLchannelsLabel = uilabel(app.AudioinputsPanel);
            app.TTLchannelsLabel.Text = 'TTL channels';
            app.Label_9 = uilabel(app.AudioinputsPanel);
            app.Label_9.Text = '5';
            app.Mic5RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic5RecordCheckBox.Text = 'Record';
            app.Mic5RecordCheckBox.Value = true;
            app.Cam5PreviewButton_2 = uibutton(app.AudioinputsPanel, 'push');
            app.Cam5PreviewButton_2.Enable = 'off';
            app.Cam5PreviewButton_2.Tooltip = {'View buttons not implemented. Use OceanAudio to live view audio signal from the microphones.'};
            app.Cam5PreviewButton_2.Text = 'View';
            app.Label_10 = uilabel(app.AudioinputsPanel);
            app.Label_10.Text = '6';
            app.Mic6RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic6RecordCheckBox.Text = 'Record';
            app.Cam6PreviewButton_2 = uibutton(app.AudioinputsPanel, 'push');
            app.Cam6PreviewButton_2.Enable = 'off';
            app.Cam6PreviewButton_2.Tooltip = {'View buttons not implemented. Use OceanAudio to live view audio signal from the microphones.'};
            app.Cam6PreviewButton_2.Text = 'View';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.ChooseSaveDir_Button = uibutton(app.SettingsPanel, 'push');
            app.ChooseSaveDir_Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseSaveDir_ButtonPushed, true);
            app.ChooseSaveDir_Button.Text = '...';
            app.SavedirEditFieldLabel = uilabel(app.SettingsPanel);
            app.SavedirEditFieldLabel.Text = 'Save dir';
            app.SaveDir_EditField = uieditfield(app.SettingsPanel, 'text');
            app.SaveDir_EditField.Editable = 'off';
            app.SaveDir_EditField.Tooltip = {'Specify root folder to save data. Data files will be save in <AnimalID>/<Date> subfolder.'};
            app.SaveDir_EditField.Value = 'C:\Users\dailyuser\Documents\MATLAB\RecordAudioVideo\data';
            app.AnimalIDEditFieldLabel_2 = uilabel(app.SettingsPanel);
            app.AnimalIDEditFieldLabel_2.Text = 'Animal ID';
            app.AnimalID_EditField = uieditfield(app.SettingsPanel, 'text');
            app.AnimalID_EditField.Value = 'smart_mouse';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.Text = 'Stop';
            app.RecordButton = uibutton(app.UIFigure, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.RecordAudioVideoLabel = uilabel(app.UIFigure);
            app.RecordAudioVideoLabel.Text = 'RecordAudioVideo';
            app.version_Label = uilabel(app.UIFigure);
            app.version_Label.Text = 'ALC v1.1 2023-07-20';
            app.TimeelapsedEditFieldLabel = uilabel(app.UIFigure);
            app.TimeelapsedEditFieldLabel.Text = 'Time elapsed ';
            app.TimeElapsedEditField = uieditfield(app.UIFigure, 'text');
            app.TimeElapsedEditField.Editable = 'off';
            app.TimeElapsedEditField.Value = '00h : 00m : 00s';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecordAudioVideo

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end